classdef TCApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        EditThresholdEditField       matlab.ui.control.NumericEditField
        EditThresholdLabel           matlab.ui.control.Label
        PracticeCheckBox             matlab.ui.control.CheckBox
        PatientConditionButtonGroup  matlab.ui.container.ButtonGroup
        PareticButton                matlab.ui.control.ToggleButton
        NonPareticButton             matlab.ui.control.ToggleButton
        TextArea                     matlab.ui.control.TextArea
        TextAreaLabel                matlab.ui.control.Label
        ThresholdMeterGauge          matlab.ui.control.SemicircularGauge
        ThresholdMeterGaugeLabel     matlab.ui.control.Label
        FeedbackTypeButtonGroup      matlab.ui.container.ButtonGroup
        NoFeedbackButton             matlab.ui.control.ToggleButton
        FeedbackButton               matlab.ui.control.ToggleButton
        EndSessionButton             matlab.ui.control.Button
        CalibrateButton              matlab.ui.control.Button
        UIAxes_mav                   matlab.ui.control.UIAxes
        UIAxes                       matlab.ui.control.UIAxes
        ContextMenu                  matlab.ui.container.ContextMenu
        Menu                         matlab.ui.container.Menu
        Menu2                        matlab.ui.container.Menu
    end

    
    properties (Access = public)
        CalibrationData %was baseline data
        DataLogger
        Timer
        RecordSession = 1;
        FeedbackType
        PatientCondition
        SessionEnd
        threshold = 0;
      
      
    end
    
    methods (Access = public)
        
        function TimerCallback(app, obj, event)
            app.CalibrateButton.BackgroundColor='white';
             app.EditThresholdEditField.Editable = 1;
              app.EditThresholdEditField.Enable = 1;
            app.NonPareticButton.BackgroundColor = 'white';
            app.PareticButton.BackgroundColor = 'white';
            app.PracticeCheckBox.Value = 0;
            disp('Calibrated.');
            stop(app.Timer);
            app.CalibrationData = app.DataLogger.getRecentEMG; %was app.BaselineData
        end
        
       
    end
        

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalibrateButton
        function CalibrateButtonPushed(app, event)
            app.Timer = timer;
            app.Timer.StartDelay = 5;
            app.Timer.TimerFcn = @app.TimerCallback;
            start(app.Timer);

            app.CalibrateButton.BackgroundColor = 'red';
        end

        % Button pushed function: EndSessionButton
        function EndSessionButtonPushed(app, event)
            app.RecordSession = 0;
        end

        % Selection changed function: PatientConditionButtonGroup
        function PatientConditionButtonGroupSelectionChanged(app, event)


            if(app.NonPareticButton == app.PatientConditionButtonGroup.SelectedObject)
               app.NonPareticButton.BackgroundColor = 'green';
               app.PareticButton.BackgroundColor = 'white';
               app.PatientCondition = 'Non-Paretic';
            end
            if(app.PareticButton == app.PatientConditionButtonGroup.SelectedObject)
              
                app.PareticButton.BackgroundColor = 'green';
                app.NonPareticButton.BackgroundColor = 'white';
                app.PatientCondition = 'Paretic';
            end


        end

        % Selection changed function: FeedbackTypeButtonGroup
        function FeedbackTypeButtonGroupSelectionChanged(app, event)

            if(app.FeedbackButton == app.FeedbackTypeButtonGroup.SelectedObject)
               app.FeedbackButton.BackgroundColor = 'green';
               app.NoFeedbackButton.BackgroundColor = 'white';
               app.FeedbackType = 'Feedback';
            end
            if(app.NoFeedbackButton == app.FeedbackTypeButtonGroup.SelectedObject)
                app.NoFeedbackButton.BackgroundColor = 'green';
                app.FeedbackButton.BackgroundColor = 'white';
                app.FeedbackType = 'No Feedback';
            end
            
        end

        % Value changed function: PracticeCheckBox
        function PracticeCheckBoxValueChanged(app, event)
          app.PracticeCheckBox.Value = 1;
            
        end

        % Value changed function: EditThresholdEditField
        function EditThresholdEditFieldValueChanged(app, event)
            % app.EditThresholdEditField.Limits = uieditfield(app.EditThresholdEditField, "numeric", "ValueDisplayFormat", "%.2f");
            app.threshold = app.EditThresholdEditField.Value;
           
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Progress')
            xlabel(app.UIAxes, 'Trial #')
            ylabel(app.UIAxes, 'Time Constant')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_mav = uiaxes(app.UIFigure);
            xlabel(app.UIAxes_mav, 'Time [s]')
            ylabel(app.UIAxes_mav, 'MAV [V]')
            zlabel(app.UIAxes_mav, 'Z')
            app.UIAxes_mav.XLim = [0 10];
            app.UIAxes_mav.YLim = [0 0.5];
            app.UIAxes_mav.XTick = [0 1 2 3 4 5 6 7 8 9 10];
            app.UIAxes_mav.XTickLabel = {'0'; '1'; '2'; '3'; '4'; '5'; '6'; '7'; '8'; '9'; '10'};
            app.UIAxes_mav.YTick = [0 0.05 0.1 0.15 0.2 0.25 0.3 0.35 0.4 0.45 0.5];
            app.CalibrateButton = uibutton(app.UIFigure, 'push');
            app.CalibrateButton.ButtonPushedFcn = createCallbackFcn(app, @CalibrateButtonPushed, true);
            app.CalibrateButton.Text = 'Calibrate';
            app.EndSessionButton = uibutton(app.UIFigure, 'push');
            app.EndSessionButton.ButtonPushedFcn = createCallbackFcn(app, @EndSessionButtonPushed, true);
            app.EndSessionButton.Text = 'End Session';
            app.FeedbackTypeButtonGroup = uibuttongroup(app.UIFigure);
            app.FeedbackTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @FeedbackTypeButtonGroupSelectionChanged, true);
            app.FeedbackTypeButtonGroup.Title = 'Feedback Type';
            app.FeedbackButton = uitogglebutton(app.FeedbackTypeButtonGroup);
            app.FeedbackButton.Text = 'Feedback';
            app.FeedbackButton.Value = true;
            app.NoFeedbackButton = uitogglebutton(app.FeedbackTypeButtonGroup);
            app.NoFeedbackButton.Text = 'No Feedback';
            app.ThresholdMeterGaugeLabel = uilabel(app.UIFigure);
            app.ThresholdMeterGaugeLabel.Text = 'Threshold Meter';
            app.ThresholdMeterGauge = uigauge(app.UIFigure, 'semicircular');
            app.TextAreaLabel = uilabel(app.UIFigure);
            app.TextAreaLabel.Text = 'Text Area';
            app.TextArea = uitextarea(app.UIFigure);
            app.PatientConditionButtonGroup = uibuttongroup(app.UIFigure);
            app.PatientConditionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PatientConditionButtonGroupSelectionChanged, true);
            app.PatientConditionButtonGroup.Title = 'Patient Condition';
            app.NonPareticButton = uitogglebutton(app.PatientConditionButtonGroup);
            app.NonPareticButton.Text = 'Non-Paretic';
            app.NonPareticButton.Value = true;
            app.PareticButton = uitogglebutton(app.PatientConditionButtonGroup);
            app.PareticButton.Text = 'Paretic';
            app.PracticeCheckBox = uicheckbox(app.UIFigure);
            app.PracticeCheckBox.ValueChangedFcn = createCallbackFcn(app, @PracticeCheckBoxValueChanged, true);
            app.PracticeCheckBox.Text = 'Practice';
            app.EditThresholdLabel = uilabel(app.UIFigure);
            app.EditThresholdLabel.Text = 'Edit Threshold';
            app.EditThresholdEditField = uieditfield(app.UIFigure, 'numeric');
            app.EditThresholdEditField.ValueChangedFcn = createCallbackFcn(app, @EditThresholdEditFieldValueChanged, true);
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Assign app.ContextMenu
            app.UIFigure.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TCApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end